classdef TAT08DesignReactiveTrTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        SavetracerplotdataMenu          matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        OutputPanel                     matlab.ui.container.Panel
        OutputPnlUITable                matlab.ui.control.Table
        AverageRateCoefficientsLabel    matlab.ui.control.Label
        PlotPanel                       matlab.ui.container.Panel
        TempUIAxes                      matlab.ui.control.UIAxes
        UIaxis                          matlab.ui.control.UIAxes
        Eq                              matlab.ui.control.Image
        EqHelpButton                    matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        UpdatePlotButton                matlab.ui.control.Button
        PlotOptPnlCurveChoiceRadioBtn   matlab.ui.container.ButtonGroup
        PlotOptPnlCurveChoiceBtnRatio   matlab.ui.control.RadioButton
        PlotOptPnlCurveChoiceBtnReaAndCon  matlab.ui.control.RadioButton
        PlotOptPnlCurveChoiceBtnRea     matlab.ui.control.RadioButton
        PlotOptions_Max_Label           matlab.ui.control.Label
        PlotOptions_Min_Label           matlab.ui.control.Label
        PlotOptions_t_Label             matlab.ui.control.Label
        PlotOptions_c_Label             matlab.ui.control.Label
        PlotOptions_T_Label             matlab.ui.control.Label
        PlotOptions_t_min_EditField     matlab.ui.control.NumericEditField
        PlotOptions_t_max_EditField     matlab.ui.control.NumericEditField
        PlotOptions_c_min_EditField     matlab.ui.control.NumericEditField
        PlotOptions_c_max_EditField     matlab.ui.control.NumericEditField
        PlotOptions_T_min_EditField     matlab.ui.control.NumericEditField
        PlotOptions_T_max_EditField     matlab.ui.control.NumericEditField
        ParamPanel                      matlab.ui.container.Panel
        UpdatePlotButton_2              matlab.ui.control.Button
        ParamPnl_M_rea_Label            matlab.ui.control.Label
        ParamPnl_M_rea_EditField        matlab.ui.control.NumericEditField
        ParamPnl_M_con_Label            matlab.ui.control.Label
        ParamPnl_M_con_EditField        matlab.ui.control.NumericEditField
        ParamPnl_Q_Label                matlab.ui.control.Label
        ParamPnl_Q_EditField            matlab.ui.control.NumericEditField
        ParamPnl_Arr_Ea_Label           matlab.ui.control.Label
        ParamPnl_Arr_Ea_EditField       matlab.ui.control.NumericEditField
        ParamPnl_Arr_A_Label            matlab.ui.control.Label
        ParamPnl_Arr_A_EditField        matlab.ui.control.NumericEditField
        ParamPnlUITable                 matlab.ui.control.Table
        ParamPnl_t_op_Label             matlab.ui.control.Label
        ParamPnl_t_op_EditField         matlab.ui.control.NumericEditField
        ParamPnl_cp_r_EditField         matlab.ui.control.NumericEditField
        ParamPnl_cp_r_Label             matlab.ui.control.Label
        ParamPnl_rho_r_EditField        matlab.ui.control.NumericEditField
        ParamPnl_rho_r_Label            matlab.ui.control.Label
        ParamPnl_T_inj_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_inj_Label            matlab.ui.control.Label
        ParamPnl_T_res_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_resLabel             matlab.ui.control.Label
        ParamPnl_nChn_EditField         matlab.ui.control.NumericEditField
        ParamPnlDeleteChannelButton     matlab.ui.control.Button
        ParamPnlAddChannelButton        matlab.ui.control.Button
        ParamPnlSumTDropDown            matlab.ui.control.DropDown
        AverageConcentrationsLabel      matlab.ui.control.Label
        ParamPnlxAxisDropDown           matlab.ui.control.DropDown
        ParamPnlDrpxAxisLabel           matlab.ui.control.Label
        ParamPnlNoChannelsLabel         matlab.ui.control.Label
        ReaTracerLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Startup *******************************************************************************************
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences

        CommonVars_Hndl                  
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignSelectReactiveTr_Hndl
        AnalysisConservativeTrTest_Hndl
        AnalysisReactiveTrTest_Hndl        
        
        % Parameters Panel
        TableCheckBox                   % Checkboxes used in the Table
        nChn                            % Number of channels for temperature profile in design workflow
        designRTTop                     % Operating time of the injection
        ParamTableDat                   % Data underlying the plot parameters table
        ParamTableDatOld                % Backup of parameter table data
        ParamPnlSumTDropDownOld         % Backup of Sum values dropdown box value
        ParamPnlxAxisDropDownOld        % Back up of Axis dropdown box value
        frac_i                          % Fraction of flow in channels

        % Plot Options Panel
        PlotOptlineStyleList    % Categorical list of options for line style
        InitialColor            % Initial line color
        PlotOptTableData        % Data for plot options table
        PlotOptionsLineTable    % Plot options table for line properties
        PlotOptPointStyleList   % Categorical list of options for point style for mean T when x-axis is distance

        % Plot Panel
        xRTValue                        % X values used in plotting
        yRTTPVar                        % Calculated temperatures
        yRTCTVar                        % Conservative tracer concentration
        yRTRTVar                        % Reactive tracer concentration
        RTSumCurve                      % Average concentration of reactive tracer
        CTSumCurve                      % Average concentration of conservative tracer
        xRTTPValue                      % Relative distance values for temperature profile plot        
    end
    
    methods (Access = private)
        
        function TATSetup(app)
        % SETUP: establishes basic information about the app
        % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Get Screen size and define scaling factors
            screenSize = get(groot,'ScreenSize');
            if screenSize(3) < app.UIFigure.Position(3)
               app.hScale = screenSize(3)/2560;
            else 
               app.hScale = 1;
            end 
            if screenSize(4) < app.UIFigure.Position(4)
               app.vScale = screenSize(4)/1440;
            else 
               app.vScale = 1;
            end   

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            mkdir(addPathNames);
            addpath(genpath(addPathNames))
            %addpath(addPathNames)

            % Set Matlab's default colors for lines
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 

        end
        
        function ParamTableInitial(app)
            app.TableCheckBox = {true; true; true; true; true; true; true; true; true; true};
            app.ParamTableDat = table(1,1,100,10,2.5,1e-2,1,100,1,1,1982759.264,67600,1);
            app.ParamPnlUITable.Data = [app.ParamTableDat(1,1:end) app.TableCheckBox(1,1)];
            app.ParamTableDat = [app.ParamTableDat app.TableCheckBox(1,1)];
            app.nChn = 1;
            app.ParamPnl_nChn_EditField.Value = app.nChn;
        end
        
        function createPlotOptTable(app)
        % Construct the Plot Options Table in the Plot Options panel
            app.PlotOptlineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});
            app.PlotOptPointStyleList = categorical({'Open', 'Filled'});
            app.InitialColor = [app.MLDefaultColors(1)];
            col1 = {1};
            col2 = [app.MLDefaultColors(1)];
            col3 = {2};
            col4 = {app.PlotOptlineStyleList(1)};
            app.PlotOptTableData = table(col1, col2, col3, col4);
            app.PlotOptionsLineTable = uitable(app.PlotOptionsPanel);
            app.PlotOptionsLineTable.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.PlotOptionsLineTable.CellSelectionCallback = createCallbackFcn(app, @PlotOptLineTableCellSelection, true);
            app.PlotOptionsLineTable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Style'};
            app.PlotOptionsLineTable.RowName = {};
            app.PlotOptionsLineTable.ColumnEditable = true;
            app.PlotOptionsLineTable.RowStriping = 'off';
            app.PlotOptionsLineTable.FontWeight = 'bold';
            app.PlotOptionsLineTable.Position = [app.hScale*205 app.vScale*15 app.hScale*401 app.vScale*185];
            app.PlotOptionsLineTable.ColumnWidth = {65 130 81 125};
            app.PlotOptionsLineTable.ColumnEditable = [false, true, true, true];
                %Initialize values associated with table
                app.PlotOptionsLineTable.Data = app.PlotOptTableData;            
            
        end
        
        function PlotOptLineTableCellSelection(app,event)
        % Provides color selection when box in color column is clicked
        indices = event.Indices;
        if ~isempty(indices) %if not clicked below active cells
            if(indices(2) == 2) %if 2nd column cell selected
                colorOld = str2num(app.PlotOptionsLineTable.Data{indices(1),2}{1});
                colorNew = num2str(round(uisetcolor(colorOld),3));
                if ~strcmp(colorNew,'0') %if operation not canceled
                    app.PlotOptionsLineTable.Data{indices(1),2}{1} = colorNew;
                    myColorStyle = uistyle("BackgroundColor",colorNew);
                    addStyle(app.PlotOptionsLineTable,myColorStyle,'cell',[indices(1),indices(2)]);          
                end
            end
        end
        end
                
        function updatePlotOptTable(app)
        % Update Plot Options Table

        % % Define columns
        % col1 = {'0';'0';'0';'0';'0';'0';'0';'0';'0';'0';'0'};
        % for i = 1:app.nChn 
        %     col1{i} = app.ParamPnlUITable.Data{i,1};
        % end
        % 
        % col2 = {app.MLDefaultColors(1);app.MLDefaultColors(2);app.MLDefaultColors(3);app.MLDefaultColors(4);app.MLDefaultColors(5);app.MLDefaultColors(6);...
        %         app.MLDefaultColors(7);app.MLDefaultColors(8);app.MLDefaultColors(9);app.MLDefaultColors(10);app.MLDefaultColors(11)};
        % if(app.ParamPnlSumTDropDown.Value == "Yes")
        %     col2{app.nChn+1,1} = app.MLDefaultColors(11);
        % end
        % 
        % col3 = {2;2;2;2;2;2;2;2;2;2;2};
        % lStyle = app.PlotOptlineStyleList(1);
        % if(app.ParamPnlxAxisDropDown.Value == "Distance" && app.ParamPnlSumTDropDown.Value == "Yes")
        %     col3{app.nChn+1,1} = 8;
        % end
        % 
        % col4 = {lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle};
        % 
        % app.PlotOptTableData = table(col1, col2, col3, col4);
        % app.PlotOptionsLineTable.Data = app.PlotOptTableData(1:app.nChn,:);

        % Update last line in Plot Options Table
        if (app.ParamPnlSumTDropDown.Value == "Yes")
            if(app.ParamPnlxAxisDropDown.Value == "Time")
                % Define newline for information for the Sum curve
                col1 = {'Sum'};
                col2 = app.MLDefaultColors(11);
                col3 = {2};
                col4 = {app.PlotOptlineStyleList(1)};
                newline = table(col1, col2, col3, col4);
            elseif (app.ParamPnlxAxisDropDown.Value == "Distance")
                % Define newline for information for the Sum curve
                col1 = {'Sum'};
                col2 = app.MLDefaultColors(11);
                col3 = {8};
                col4 = {app.PlotOptPointStyleList(1)};
                newline = table(col1, col2, col3, col4);
            end
            % Append new line to Plot Option table and display
            app.PlotOptionsLineTable.Data  = [app.PlotOptionsLineTable.Data; newline];
        end

        % % Put Active window on top center
        % movegui(app.UIFigure,'center');
        % uistack(app.UIFigure,'top');                  
        end

        % Check for potential input error related to the plot        
        function TPPlotInputErrorCheck(app)
            
        end
        
        function updateRTParamPnl(app)
        % Updates parameter panel, and plot axis with changes in parameters

            app.nChn = app.nChn;
            % Save whether to include curve in plot or not 
            includeCurve(:) = table2array(app.ParamTableDat(:,end));
            
            % Reassign the default values of ParamPnlParamUITable to the read values
            for i = 1:app.nChn
                app.TableCheckBox{i} = logical(includeCurve(i));
            end

            % Calculate fractional flows
            UpDateFraction(app);

            % Concatenate current table and designConParamTableCheckbox
            if (size(app.ParamTableDat,2) == 14)
                app.ParamTableDat(:,end-1:end) = []; % delete last two columns in data matrix for Parameter Table
            else
                app.ParamTableDat(:,end) = []; % delete last column in data matrix for Parameter Table
            end
            app.ParamPnlUITable.Data(:,end) = []; % delete last column of Parameter Table data

            % Concatenate current table with fractional flow
            app.ParamTableDat = addvars(app.ParamTableDat, app.frac_i(1:app.nChn,1),'NewVariableNames',{'Fraction'});

            % Concatenate current table and designConParamTableCheckbox and map to Parameter Table Data            
            app.ParamTableDat = [app.ParamTableDat app.TableCheckBox(1:app.nChn,1)];
            app.ParamPnlUITable.Data = app.ParamTableDat;

            % Display Table
            % First define table entries and visibility of Op. Time Edit Fields
            if (app.ParamPnlSumTDropDown.Value == "Yes" && app.ParamPnlxAxisDropDown.Value == "Time")
                app.ParamPnlUITable.Data = app.ParamTableDat(1:app.nChn,[1:6,13,14]);
                app.ParamPnlUITable.ColumnFormat = {'numeric', 'numeric','numeric','numeric','numeric', 'numeric','numeric','logical'};
                app.ParamPnlUITable.ColumnName = ["Channel", "Velocity", "Path Length", "alpha","Lambda (r)","Aperture","Fraction","Include"];
                app.ParamPnlUITable.ColumnEditable = [true, true, true, true, true, true, false, true];

                % Pumping Rate
                app.ParamPnl_Q_Label.Visible = 'on';
                app.ParamPnl_Q_EditField.Visible = 'on';

                % Operating TimenChn
                app.ParamPnl_t_op_Label.Visible = 'on';
                app.ParamPnl_t_op_EditField.Visible = 'on';

                % Mass of conservative tracer
                app.ParamPnl_M_con_Label.Visible = 'on';
                app.ParamPnl_M_con_EditField.Visible = 'on';

                % Mass of reactive tracer
                app.ParamPnl_M_rea_Label.Visible = 'on';
                app.ParamPnl_M_rea_EditField.Visible = 'on';

                % Pre-Exponential Factor
                app.ParamPnl_Arr_A_Label.Visible = 'on';
                app.ParamPnl_Arr_A_EditField.Visible = 'on';

                % Activation Energy
                app.ParamPnl_Arr_Ea_Label.Visible = 'on';
                app.ParamPnl_Arr_Ea_EditField.Visible = 'on';
                
            elseif (app.ParamPnlSumTDropDown.Value == "No" && app.ParamPnlxAxisDropDown.Value == "Time")
                app.ParamPnlUITable.Data = app.ParamTableDat(1:app.nChn,1:14);
                app.ParamPnlUITable.ColumnFormat = {'numeric', 'numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','logical'};
                app.ParamPnlUITable.ColumnName = ["Channel", "Velocity", "Path Length", "alpha","Lambda (r)","Aperture","Pumping Rate","Op. Time","Mass(Con)","Mass(Rea)","A","Ea","Fraction","Include"];
                app.ParamPnlUITable.ColumnEditable = [true, true, true, true, true, true, true, true, true, true, true, true, false, true];

                % Pumping Rate
                app.ParamPnl_Q_Label.Visible = 'off';
                app.ParamPnl_Q_EditField.Visible = 'off';

                % Operating Time:
                app.ParamPnl_t_op_Label.Visible = 'off';
                app.ParamPnl_t_op_EditField.Visible = 'off';

                % Mass of conservative tracer
                app.ParamPnl_M_con_Label.Visible = 'off';
                app.ParamPnl_M_con_EditField.Visible = 'off';

                % Mass of reactive tracer
                app.ParamPnl_M_rea_Label.Visible = 'off';
                app.ParamPnl_M_rea_EditField.Visible = 'off';

                % Pre-Exponential Factor
                app.ParamPnl_Arr_A_Label.Visible = 'off';
                app.ParamPnl_Arr_A_EditField.Visible = 'off';

                % Activation Energy
                app.ParamPnl_Arr_Ea_Label.Visible = 'off';
                app.ParamPnl_Arr_Ea_EditField.Visible = 'off';
                
            end    

            % Update x-axis label
            if (app.ParamPnlxAxisDropDown.Value == "Time")
                app.UIaxis.XLabel.String = 'Time';
            else
                app.UIaxis.XLabel.String = 'Relative Distance';
                app.PlotOptions_t_min_EditField.Value = 0;
                app.PlotOptions_t_max_EditField.Value = 1;
            end
        end
        
        function ytp = RTTPCalc(app)
        % TTProfileCalc: Setup temp. profile calculation and call routine to do calculation
            
            % Get the temperatures, calculate fluid heat capacity and
            % density, get rock heat density and heat capacity
            T_res = app.ParamPnl_T_res_EditField.Value;
            T_inj = app.ParamPnl_T_inj_EditField.Value;
            rho_f = rhoH2OCalc(T_res);
            cp_f = cpH2OCalc(T_res);
            rho_r = app.ParamPnl_rho_r_EditField.Value;
            cp_r = app.ParamPnl_cp_r_EditField.Value;
            
            % Get Common parameters from temperature profile parameter table
            v = table2array(app.ParamPnlUITable.Data(:,2));
            L = table2array(app.ParamPnlUITable.Data(:,3));
            lambda_r = table2array(app.ParamPnlUITable.Data(:,5));
            b = table2array(app.ParamPnlUITable.Data(:,6));
            includeRT = table2array(app.ParamPnlUITable.Data(:,end));
            
            % Get x-axis values and Path Lengths
            npts = 101;
	        xr = linspace(0,1,npts);
            app.xRTTPValue = linspace(0, 1, npts);
            if (app.ParamPnlSumTDropDown.Value=="No")
               time = table2array(app.ParamPnlUITable.Data(:,8));
            elseif (app.ParamPnlSumTDropDown.Value=="Yes")
               time(1:app.nChn,1) = app.ParamPnl_t_op_EditField.Value;
            end
            
            % Calculate the temperature profile for all curves in the parameter table
            % axisType = "Distance"; %app.ParamPnlxAxisDropDown.Value;
            % ytp=TempProfileCJ(axisType,lambda_r,L,xr,v,b,cp_f,cp_r,rho_f,rho_r,time,app.nChn,T_res,T_inj,npts);
ytp=TempProfile(lambda_r,L,xr,v,b,cp_f,cp_r,rho_f,rho_r,time,T_res,T_inj);
        end
        
        function UpDateFraction(app)
            % Calculate fractional flows in channels
            if(app.ParamPnlSumTDropDown.Value == "Yes") 
                v = table2array(app.ParamTableDat(:,2));
                b = table2array(app.ParamTableDat(:,6));
                includeRT = table2array(app.ParamTableDat(:,end));
                app.frac_i = QFracCalc(v,b,includeRT);
            else
                app.frac_i(1:app.nChn,1) = 1;
            end
        end
        
        function y = RTCTCalc(app)
        % RTCTCalc: calculates concentration of conservative tracer
        % Inputs velocity(V) mass of tracer (M), extraction rate (Q),
        % dispersity (alpha), and distance between injection and extraction (L)

            % Get the temperature and calculate fluid density
            tempC = app.ParamPnl_T_res_EditField.Value;
            rho_f = rhoH2OCalc(tempC);
    
            % Get conservative tracer parameter data from table
            v = table2array(app.ParamPnlUITable.Data(:,2));
    
            % Parameters if Sum == "No"
            if (app.ParamPnlSumTDropDown.Value == "No")
                MC = table2array(app.ParamPnlUITable.Data(:,9));
                Q = table2array(app.ParamPnlUITable.Data(:,7));
                alpha = table2array(app.ParamPnlUITable.Data(:,4));
             % Parameter if Sum == "Yes"
            else
                fi = table2array(app.ParamPnlUITable.Data(:,end-1));
                MC = app.ParamPnl_M_con_EditField.Value*fi;
                Q = app.ParamPnl_Q_EditField.Value*transpose(linspace(1,1,app.nChn));
                alpha = table2array(app.ParamPnlUITable.Data(:,4));
            end
    
            % Get x-axis values and Path Lengths
            npts = 101;
            app.xRTValue = linspace(app.PlotOptions_t_min_EditField.Value, app.PlotOptions_t_max_EditField.Value, npts);
            if (app.xRTValue(1) == 0)
                app.xRTValue(1) = app.xRTValue(2)/10;
            end
            time = app.xRTValue;
            L = table2array(app.ParamPnlUITable.Data(:,3));
    
            % Calculate concentrations for all curves in conservative tracer in parameter table
            y = ConservativeTracer(v, MC, Q, alpha,L, time, rho_f);
                    
        end
        
        function [lamdaBar,decayFactor] = RTDFCalc(app)
        % RTDFCalc: calculate the decay factor for a given temperature profile

            % Get data from the Parameter Table
            v = table2array(app.ParamPnlUITable.Data(:,2));
            L = table2array(app.ParamPnlUITable.Data(:,3));
            if (app.ParamPnlSumTDropDown.Value == "No")
                A = table2array(app.ParamPnlUITable.Data(:,11));
                Ea = table2array(app.ParamPnlUITable.Data(:,12));
                Mc = table2array(app.ParamPnlUITable.Data(:,9));
                Mr = table2array(app.ParamPnlUITable.Data(:,10));
            else
                A = app.ParamPnl_Arr_A_EditField.Value;
                Ea = app.ParamPnl_Arr_Ea_EditField.Value;
                Mc = app.ParamPnl_M_con_EditField.Value;
                Mr = app.ParamPnl_M_rea_EditField.Value;
            end

            % Define Constants
            R_gas = 8.31441; % J/(mol K)
            Tk = app.yRTTPVar + 273.15;

            % Calculate integrand and calculate x values
            integrand = exp(-Ea./(R_gas*Tk));
            x_rel = linspace(0,1,101);

            % Calculate Integral            
            lamdaBar = zeros(app.nChn,1); % Preallocate vector 
            for i = 1:app.nChn
                lamdaBar(i,1) = trapz(x_rel,integrand(i,:),2);   %MAP
            end
            decayFactor = (Mr./Mc).*exp(-A.*L.*lamdaBar./v);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get screen size and define hScale, vScale, and plot default colors
            TATSetup(app)

            % Initialize Parameter Table
            ParamTableInitial(app)

            % Create Components
            createPlotOptTable(app)                  % Table for plot options
        end

        % Value changed function: ParamPnlxAxisDropDown
        function ParamPnlxAxisDropDownValueChanged(app, event)
            % Update Parameter Table, Parameter Panel, x-axis of plot
            updateRTParamPnl(app);

            % Update Plot Options Table
            updatePlotOptTable(app)

            % Update label and values for min and max on x-axis
            if (app.ParamPnlxAxisDropDown.Value == "Time")
                app.PlotOptions_t_Label.Text = "Time";
                app.PlotOptions_t_min_EditField.Value = 0;
                app.PlotOptions_t_max_EditField.Value = 1000;
            else
                app.PlotOptions_t_Label.Text= "Rel. Dist.";
                app.PlotOptions_t_min_EditField.Value = 0;
                app.PlotOptions_t_max_EditField.Value = 1;
            end
        end

        % Button pushed function: ParamPnlDeleteChannelButton
        function ParamPnlDeleteChannelButtonPushed(app, event)
        % Button pushed function: ParamPnlDeleteChannelButton
        % Open dialogue box to input channels to be deleted

            % Create response dialogbox and get response
 	        prompt = {'Enter space-separated channel numters:'};
            deleteDlgTitle = 'Delete Channels';
            dims = [1,40];
            deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
            if(isempty(deleteResponse))
                return
            end
            deleteChn = str2num(deleteResponse{1});
            nDelete = size(deleteChn,2);
            if(nDelete == 0)
                return
            end

            % Determine the row for each channel to be deleted and add it
            % to the deleteRows vector
            for i = 1:nDelete
                 % Set error code for finding channel to 1
                chnFindError = 1;
                for j = 1: app.nChn
                    if (deleteChn(i) == app.ParamPnlUITable.Data{j,1})
                        deleteRows(1,i) = j;
                        chnFindError = 0;
                        break;
                    end
                end

                % Display error message if channel number not found
                if (chnFindError == 1)
                    errTxt = ['Channel ' num2str(deleteChn(i)) ' not found. Check input.'];
                    errordlg(errTxt,'Input Error')
                    return
                end
            end

            % Delete rows from table if all channels found
            app.ParamPnlUITable.Data(deleteRows,:) = [];
            app.PlotOptionsLineTable.Data(deleteRows,:) = [];
            app.ParamTableDat(deleteRows,:) = [];
            app.nChn = app.nChn - nDelete;
            app.ParamPnl_nChn_EditField.Value = app.nChn;

            % Update table with new fractional flow values
            UpDateFraction(app);
        end

        % Button pushed function: UpdatePlotButton, UpdatePlotButton_2
        function UpdatePlotButtonPushed(app, event)
        % UpdatePlotButtonPushed: plots temperature versus time or distance
        % Input variables: all from global definition

        % Get the channel numbers from the temperature profile parameter table
        ChnNo = table2array(app.ParamPnlUITable.Data(:,1));
        includeRT = table2array(app.ParamPnlUITable.Data(:,end));
        if (app.ParamPnlSumTDropDown.Value == "Yes")
            app.frac_i = table2array(app.ParamPnlUITable.Data(:,end-1));
        else
            app.frac_i(1:app.nChn,1) = 1;
        end
    
        % Check for Input Errors 
        TPPlotInputErrorCheck(app);

        % Calculate Curves
        app.yRTTPVar = RTTPCalc(app);               % Temperature Profile
        app.yRTCTVar = RTCTCalc(app);               % Conservative Tracer
        [lambdaBar,decayFactor] = RTDFCalc(app);    % Decay factor
        app.yRTRTVar = decayFactor.*app.yRTCTVar;   % Reactive Tracer concentrations

        % Write lambdaBar to output table
        k=1;
        for i = 1:app.nChn
            if (includeRT(i) == 1)
                col1{k,1} = num2str(app.ParamPnlUITable.Data{i,1});
                col2{k,1} = lambdaBar(i);
                k = k+1;
            end
        end
        app.OutputPnlUITable.Data = [col1, col2];

        % Set the y variable depending on Ratio checkbox
%MAP    if (app.designPlotOptPnlRatioCheckBox.Value == true)
        if(app.PlotOptPnlCurveChoiceBtnRatio.Value == true) 
            yVar = app.yRTRTVar./app.yRTCTVar;
        else
            yVar = app.yRTRTVar;
        end
%MAP
        % Get Plot Options
         endloop = app.nChn;
        if(app.ParamPnlSumTDropDown.Value == "Yes")
            endloop = app.nChn + 1;
        end
        % for i = 1: endloop
        %     % Linstyle
        %     if(app.PlotOptionsLineTable.Data{i,4}{1} == "Solid")
        %         linstyle(i) = "-";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "Dash-Dotted")
        %         linstyle(i) = "-.";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "Dashed")
        %         linstyle(i) = "--";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "Dotted")
        %         linstyle(i) = ":";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "none")
        %         linstyle(i) = "none";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "Open")
        %         linstyle(i) = "Open";
        %     elseif (app.PlotOptionsLineTable.Data{i,4}{1} == "Filled")
        %         linstyle(i) = "Filled";                
        %     end
        %     linWidth(i) = app.PlotOptionsLineTable.Data{i,3};
        %     linColor{i} = app.PlotOptionsLineTable.Data{i,2}{1};
        % end
%MAP
            % Convert linestyle descriptions to linestyles
            LineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});  
            ActualLineStyleList = {'-';'--';':';'-.';''};
            [~,loc] = ismember(string(app.PlotOptionsLineTable.Data.col4(1:endloop)), string(LineStyleList'));
            linstyle = ActualLineStyleList(loc);   
            linWidth = app.PlotOptionsLineTable.Data.col3(1:endloop);
            linColor = app.PlotOptionsLineTable.Data.col2(1:endloop);          
%MAP
        % Plot the included graphs
        hold(app.UIaxis,"off");
        hold(app.TempUIAxes,"off");
        ChnStr = "Chn ";
        TypeStr1 = " Con";
        TypeStr2 = " Rea";
        legendList = [];
        xlim(app.UIaxis,[app.PlotOptions_t_min_EditField.Value, app.PlotOptions_t_max_EditField.Value]);
        for i = 1:app.nChn
            if includeRT(i) == true
                plot(app.UIaxis,app.xRTValue,yVar(i,:),"LineWidth",linWidth{i},"LineStyle",linstyle(i),"Color",(linColor{i}));
                plot(app.TempUIAxes,app.xRTTPValue,app.yRTTPVar(i,:),"LineWidth",linWidth{i},"LineStyle",linstyle(i),"Color",(linColor{i}));
                tempStr = strcat(ChnStr,num2str(ChnNo(i)),TypeStr2);
                legendList = [legendList tempStr];
                hold(app.UIaxis,"on");
                hold(app.TempUIAxes,"on");
%MAP            if (app.PlotConservativeTracerDataCheckBox.Value == true && app.designPlotOptPnlRatioCheckBox.Value == false)
                if (app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
                    linstyleValue = ":";
                    if (linstyle(i) == "none")
                        linstyleValue = "none";
                    end
                    plot(app.UIaxis,app.xRTValue,app.yRTCTVar(i,:),"LineWidth",linWidth{i},"LineStyle",linstyleValue,"Color",linColor{i});
                    tempStr = strcat(ChnStr,num2str(ChnNo(i)),TypeStr1);
                    legendList = [legendList tempStr];
                end
            end
        end
        legendListTracer = legendList;

        % Add the plot of the sum of the curves
        if(app.ParamPnlSumTDropDown.Value == "Yes")
            % Add the curves
            app.RTSumCurve = transpose(includeRT)*app.yRTRTVar;
            app.CTSumCurve = transpose(includeRT)*app.yRTCTVar;
            sumCurve = app.RTSumCurve;
%MAP        if (app.designPlotOptPnlRatioCheckBox.Value == true)
            if (app.PlotOptPnlCurveChoiceBtnRatio.Value == true)
                sumCurve = app.RTSumCurve./app.CTSumCurve;
            end
            % Plot curve
            plot(app.UIaxis,app.xRTValue,sumCurve,"LineWidth",linWidth{endloop},"LineStyle",linstyle(endloop),"color",linColor{endloop});
            legendListTracer = [legendList "SumRea"];
%MAP        if (app.PlotConservativeTracerDataCheckBox.Value == true && app.designPlotOptPnlRatioCheckBox.Value == false)
            if (app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == 1)
                plot(app.UIaxis,app.xRTValue,app.CTSumCurve,"LineWidth",linWidth{endloop},"LineStyle",":","Color",linColor{endloop});
                legendListTracer = [legendList "SumCon"];
            end
        end

        % Add legend to plot
        legend(app.UIaxis,legendListTracer);
        legend(app.TempUIAxes,legendList);
        ylim(app.UIaxis,[app.PlotOptions_c_min_EditField.Value, app.PlotOptions_c_max_EditField.Value]);
        ylim(app.TempUIAxes,[app.PlotOptions_T_min_EditField.Value, app.PlotOptions_T_max_EditField.Value]);

        end

        % Value changed function: ParamPnlSumTDropDown
        function ParamPnlSumTDropDownValueChanged(app, event)
            % Update Parameter Table, Parameter Panel, x-axis of plot
            updateRTParamPnl(app);

            %Update Plot Options Table
            updatePlotOptTable(app)
        end

        % Button pushed function: ParamPnlAddChannelButton
        function ParamPnlAddChannelButtonPushed(app, event)
            % Check if nChn <= 10
            if(app.nChn < 10)
                if(app.nChn == 0)
                    maxChn = 0;
                else
                    % Find the largest channel number
                    maxChn = app.ParamTableDat{1,1};
                    for i = 2:app.nChn
                        if(app.ParamTableDat {i,1} > maxChn)
                            maxChn = app.ParamTableDat {i,1};
                        end
                    end
                end
                maxChn = maxChn + 1;

                % Increment the number of channels and update editfield displaying designConnChn
                app.nChn = app.nChn + 1;
                app.ParamPnl_nChn_EditField.Value = app.nChn;

                % Create new row, as copy of last row of table
                newRow = app.ParamTableDat(end,:);
                newRow.Var1 = maxChn;
                app.ParamTableDat  = [app.ParamTableDat; newRow];   

                % Update Parameters Table
                updateRTParamPnl(app)

                % Generate New Line for Plot Options Table
                % updatePlotOptTable(app)
                if (app.ParamPnlSumTDropDown.Value == "Yes")
                    app.PlotOptionsLineTable.Data = [app.PlotOptTableData(1:app.nChn-1,:) ; 
                                                    {0,app.MLDefaultColors(app.nChn), 2, "Dashed"};
                                                    app.PlotOptTableData(1:app.nChn+1,:)];
                else
                    app.PlotOptionsLineTable.Data = [app.PlotOptTableData(1:app.nChn-1,:); 
                                                    {0,app.MLDefaultColors(app.nChn), 2, "Dashed"}];
                end

                % Update the background color of the line color cells
                    % app.PlotOptionsLineTable.Data{indices(1),2}{1} = colorNew;
                    % myColorStyle = uistyle("BackgroundColor",colorNew);
                    % addStyle(app.PlotOptionsLineTable,myColorStyle,'cell',[indices(1),indices(2)]);                    

            else
               errordlg('Number of channels must be <= 10','Input Error')
               return
            end

        end

        % Cell edit callback: ParamPnlUITable
        function ParamPnlUITableCellEdit(app, event)
            % Get the table indices for cell being edited
            indices = event.Indices;
            newData = event.NewData;

            % Determine new second index for mapping to correct cell
            if(app.ParamPnlSumTDropDown.Value == "No")
                newIndex = indices(2);
            else
                if (indices(2) <= 6)
                    newIndex = indices(2);
                else
                    newIndex = indices(2) + 6;
                end
            end

            % Save the old data
            oldData = app.ParamTableDat{indices(1),newIndex};

            % Check for duplicate channel number
            for i = 1 : app.nChn
                if(indices(2) == 1 && i ~= indices(1) && newData == app.ParamTableDat{i,1})
                        errTxt = [ 'Channel ' num2str(newData) ' already exists. Choose another channel number.'];
                        errordlg(errTxt, 'Input Error')
                        newData = oldData;
                        break
                end
            end
            
            % Update data value
            app.ParamTableDat{indices(1), newIndex} = newData;
            app.ParamPnlUITable.Data{indices(1),indices(2)} = newData; %app.designCont{indices(1),indices(2)};

            % Calculate fractional flows
            UpDateFraction(app);

            % Insert updated fractional flow into parameter table
            app.ParamTableDat(:,end-1) = num2cell(app.frac_i);
            app.ParamPnlUITable.Data(:,end-1) = num2cell(app.frac_i);

            % Update Plot Options Table
            updatePlotOptTable(app)            
            
        end

        % Callback function
        function SavePlotDataButtonPushed(app, event)
        % Prep for saving plot file for conservative tracer

        % Get the channel numbers from the conservative tracer parameter table
        ChnNo = table2array(app.ParamPnlUITable.Data(:,1));
        includeTr = table2array(app.ParamPnlUITable.Data(:,end));

        % Initialize Variables
        ChnStr1 = "RTChn";
        ChnStr2 = "CTChn";
        ChnStr2 = "Ratio";
        saveList = [];
        headerRea = [];
        headerCon = [];
        
        % Get channel numbers and table positions of curves included in the plot
        for i = 1:app.nChn
            if includeTr(i) == true
                k = i;
                if(app.PlotOptPnlCurveChoiceBtnRatio.Value == false)
                    tempStr = strcat(ChnStr1,num2str(ChnNo(i)));
                else
                    tempStr = strcat(ChnStr2,num2str(ChnNo(i)));
                end
                saveList = [saveList k];
                headerRea = [headerRea tempStr];
                if (app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == 1)
                	tempStr = strcat(ChnStr2,num2str(ChnNo(i)));
                	headerCon = [headerCon tempStr];
                end
            end
        end

        % Create Headers for data file
        headerx = "Time";

        % Create data matrix and final header based on whether or not
        % concentrations are summed
        xVar = app.xRTValue;
        yVarRea = app.yRTRTVar(saveList,:);
        yVarCon = app.yRTCTVar(saveList,:);
        yVarSum = yVarRea./yVarCon;
        
        if(app.ParamPnlSumTDropDown.Value == "No" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
            header = [headerx headerRea];
            if(app.PlotOptPnlCurveChoiceBtnRatio.Value == false)
                dataMatrix = [xVar' yVarRea'];
            else
                dataMatrix = [xVar' yVarSum'];
            end
        elseif (app.ParamPnlSumTDropDown.Value == "No" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
            header = [headerx headerRea headerCon];
            dataMatrix = [xVar' yVarRea' yVarCon'];
        elseif (app.ParamPnlSumTDropDown.Value == "Yes" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
            if(app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
                header = [headerx headerRea "SumRea"];
                dataMatrix = [xVar' yVarRea' app.RTSumCurve'];
            else
               header = [headerx headerRea "SumRatio"];
                dataMatrix = [xVar' yVarSum']; 
            end
        elseif (app.ParamPnlSumTDropDown.Value == "Yes" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
            header = [headerx headerRea "SumRea" headerCon "SumCon"];
            dataMatrix = [xVar' yVarRea' app.RTSumCurve' yVarCon' app.CTSumCurve'];
        end
        % Call function to save the file
        plotSave(app, header, dataMatrix)             
        end

        % Menu selected function: SavetracerplotdataMenu
        function SavetracerplotdataMenuSelected(app, event)
        % Prep for saving plot file for conservative tracer

        % Get the channel numbers from the conservative tracer parameter table
        ChnNo = table2array(app.ParamPnlUITable.Data(:,1));
        includeTr = table2array(app.ParamPnlUITable.Data(:,end));

        % Initialize Variables
        ChnStr1 = "RTChn";
        ChnStr2 = "CTChn";
        ChnStr2 = "Ratio";
        saveList = [];
        headerRea = [];
        headerCon = [];
        
        % Get channel numbers and table positions of curves included in the plot
        for i = 1:app.nChn
            if includeTr(i) == true
                k = i;
                if(app.PlotOptPnlCurveChoiceBtnRatio.Value == false)
                    tempStr = strcat(ChnStr1,num2str(ChnNo(i)));
                else
                    tempStr = strcat(ChnStr2,num2str(ChnNo(i)));
                end
                saveList = [saveList k];
                headerRea = [headerRea tempStr];
                if (app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == 1)
                	tempStr = strcat(ChnStr2,num2str(ChnNo(i)));
                	headerCon = [headerCon tempStr];
                end
            end
        end

        % Create Headers for data file
        headerx = "Time";

        % Create data matrix and final header based on whether or not
        % concentrations are summed
        xVar = app.xRTValue;
        yVarRea = app.yRTRTVar(saveList,:);
        yVarCon = app.yRTCTVar(saveList,:);
        yVarSum = yVarRea./yVarCon;
        
        if(app.ParamPnlSumTDropDown.Value == "No" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
            header = [headerx headerRea];
            if(app.PlotOptPnlCurveChoiceBtnRatio.Value == false)
                dataMatrix = [xVar' yVarRea'];
            else
                dataMatrix = [xVar' yVarSum'];
            end
        elseif (app.ParamPnlSumTDropDown.Value == "No" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
            header = [headerx headerRea headerCon];
            dataMatrix = [xVar' yVarRea' yVarCon'];
        elseif (app.ParamPnlSumTDropDown.Value == "Yes" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
            if(app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == false)
                header = [headerx headerRea "SumRea"];
                dataMatrix = [xVar' yVarRea' app.RTSumCurve'];
            else
               header = [headerx headerRea "SumRatio"];
                dataMatrix = [xVar' yVarSum']; 
            end
        elseif (app.ParamPnlSumTDropDown.Value == "Yes" && app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
            header = [headerx headerRea "SumRea" headerCon "SumCon"];
            dataMatrix = [xVar' yVarRea' app.RTSumCurve' yVarCon' app.CTSumCurve'];
        end
        % Call function to save the file
        plotSave(app, header, dataMatrix)              
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
        % designRTEqHelpButtonPushed: Opens help file for the conservative tracer

            % Define file location
            eqTPHelpPath = fullfile(app.TATPath,'Docs','RTTracerHelp.pdf');
            
            % Open Manual
            winopen(eqTPHelpPath)            
        end

        % Callback function
        function PlotConservativeTracerDataCheckBoxValueChanged(app, event)
            %value = app.PlotConservativeTracerDataCheckBox.Value;
            if (app.designPlotOptPnlRatioCheckBox.Value == true)
                app.PlotConservativeTracerDataCheckBox.Value = false;
                errordlg('Choose only one of the checkboxes','Input Error')
            end
        end

        % Value changed function: ParamPnl_T_res_EditField
        function ParamPnl_T_res_EditFieldValueChanged(app, event)
            value = app.ParamPnl_T_res_EditField.Value;
            app.PlotOptions_T_max_EditField.Value = value;
        end

        % Value changed function: ParamPnl_T_inj_EditField
        function ParamPnl_T_inj_EditFieldValueChanged(app, event)
            value = app.ParamPnl_T_inj_EditField.Value;
            app.PlotOptions_T_min_EditField.Value = value;
        end

        % Selection changed function: PlotOptPnlCurveChoiceRadioBtn
        function designPlotOptPnlCurveChoiceBtn(app, event)
            if (app.PlotOptPnlCurveChoiceBtnRatio.Value == true)
                app.PlotOptions_c_Label.Text = "Ratio";
                ylabel(app.UIaxis, 'Reactive Tr/Conservative Tr');           
            else
                app.PlotOptions_c_Label.Text = "Conc.";
                ylabel(app.UIaxis, 'Concentration');
            end            
        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
        % Select file to save to
        [filename,pathname] = uiputfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the preexisting structure if file exists
        if isfile(fileNamefull)
            Struct = read_namelist(fileNamefull);
        end
      
        % Conservative tracer test data - 
        % Editfields
        Struct.DesignConservativeTrTest.nChn        = app.ParamPnl_nChn_EditField.Value;
        Struct.DesignConservativeTrTest.T_res       = app.ParamPnl_T_res_EditField.Value;
        Struct.DesignConservativeTrTest.Q           = app.ParamPnl_Q_EditField.Value;      
        Struct.DesignConservativeTrTest.M_con       = app.ParamPnl_M_con_EditField.Value;
        Struct.DesignConservativeTrTest.axisType    = {app.ParamPnlxAxisDropDown.Value} ;
        Struct.DesignConservativeTrTest.SumConc     = {appParamPnlSumTDropDown.Value};

        % Channel data table
        Struct.DesignConservativeTrTest.Table_ChNum     	= app.ParamPnlUITable.Data{:,1}		; 
        Struct.DesignConservativeTrTest.Table_Q		        = app.ParamPnlUITable.Data{:,7}		; 
        Struct.DesignConservativeTrTest.Table_M_con     	= app.ParamPnlUITable.Data{:,9}		; 
        Struct.DesignConservativeTrTest.Table_v         	= app.ParamPnlUITable.Data{:,2}		; 
        Struct.DesignConservativeTrTest.Table_L         	= app.ParamPnlUITable.Data{:,3}		; 
        Struct.DesignConservativeTrTest.Table_f     	    = app.ParamPnlUITable.Data{:,13}	; 
        Struct.DesignConservativeTrTest.Table_alpha         = app.ParamPnlUITable.Data{:,4}		; 
        Struct.DesignConservativeTrTest.Table_include     	= double(app.ParamPnlUITable.Data{:,14}); 

        % Reactive tracer test data
        % Editfields
        Struct.DesignReactiveTrTest.T_inj       = app.ParamPnl_T_inj_EditField.Value;
        Struct.DesignReactiveTrTest.t_op        = app.ParamPnl_t_op_EditField.Value;
        Struct.DesignReactiveTrTest.rho_r       = app.ParamPnl_rho_r_EditField.Value;
        Struct.DesignReactiveTrTest.cp_r        = app.ParamPnl_cp_r_EditField.Value;
        Struct.DesignReactiveTrTest.M_rea       = app.ParamPnl_M_rea_EditField.Value;
        Struct.DesignReactiveTrTest.Arr_A       = app.ParamPnl_Arr_A_EditField.Value;
        Struct.DesignReactiveTrTest.Arr_Ea      = app.ParamPnl_Arr_Ea_EditField.Value;
               
        % Channel data table
        Struct.DesignReactiveTrTest.Table_lambda_r  	= app.ParamPnlUITable.Data{:,5}		; 
        Struct.DesignReactiveTrTest.Table_b         	= app.ParamPnlUITable.Data{:,6}		; 
        Struct.DesignReactiveTrTest.Table_M_rea     	= app.ParamPnlUITable.Data{:,10}	; 
        Struct.DesignReactiveTrTest.Table_Arr_A     	= app.ParamPnlUITable.Data{:,11}	; 
        Struct.DesignReactiveTrTest.Table_Arr_Ea     	= app.ParamPnlUITable.Data{:,12}	; 
        Struct.DesignReactiveTrTest.Table_t_op   	    = app.ParamPnlUITable.Data{:,8}		; 

        % Plot Options Editfields
        Struct.DesignReactiveTrTest.t_min       = app.PlotOptions_t_min_EditField.Value;
        Struct.DesignReactiveTrTest.t_max       = app.PlotOptions_t_max_EditField.Value;
        Struct.DesignReactiveTrTest.c_min       = app.PlotOptions_c_min_EditField.Value;
        Struct.DesignReactiveTrTest.c_max       = app.PlotOptions_c_max_EditField.Value;
        Struct.DesignReactiveTrTest.T_min       = app.PlotOptions_T_min_EditField.Value;
        Struct.DesignReactiveTrTest.T_max       = app.PlotOptions_T_max_EditField.Value;

        % Save structure to file
        write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the structure 
        Struct = read_namelist(fileNamefull);

        if ~isfield(Struct,'DesignConservativeTrTest')
            msgbox('No tracer test design data found; execution halted')
            return
        end            
      
        % Editfields
        app.ParamPnl_nChn_EditField.Value	    = Struct.DesignConservativeTrTest.nChn    ;
        app.ParamPnl_T_res_EditField.Value	    = Struct.DesignConservativeTrTest.T_res   ;
        app.ParamPnl_t_op_EditField.Value	    = Struct.DesignConservativeTrTest.t_op    ;
        app.ParamPnl_M_con_EditField.Value      = Struct.DesignConservativeTrTest.M_con   ;
        app.ParamPnlxAxisDropDown.Value         = Struct.DesignConservativeTrTest.axisType; 
        appParamPnlSumTDropDown.Value           = Struct.DesignConservativeTrTest.SumConc ;

        % Channel data table
        nChan = Struct.DesignConservativeTrTest.nChn;
        app.ParamPnlUITable.Data = [];
        app.ParamPnlUITable.Data(1:nChan,1)		= Struct.DesignConservativeTrTest.Table_ChNum'     	 	    ; 
        app.ParamPnlUITable.Data(1:nChan,2)		= Struct.DesignConservativeTrTest.Table_v'         	 	    ; 
        app.ParamPnlUITable.Data(1:nChan,3)		= Struct.DesignConservativeTrTest.Table_L'         	 	    ; 
        app.ParamPnlUITable.Data(1:nChan,4)		= Struct.DesignConservativeTrTest.Table_alpha'        	    ; 
        app.ParamPnlUITable.Data(1:nChan,7)		= Struct.DesignConservativeTrTest.Table_Q'		 	        ; 
        app.ParamPnlUITable.Data(1:nChan,9)		= Struct.DesignConservativeTrTest.Table_M_con'     	 	    ; 
        app.ParamPnlUITable.Data(1:nChan,13)	= Struct.DesignConservativeTrTest.Table_f'     	 	        ;
        app.ParamPnlUITable.Data(1:nChan,14)	= logical(Struct.DesignConservativeTrTest.Table_include)'	; 

        if  ~isfield(Struct,'DesignReactiveTrTest')
            msgbox('No reactive tracer test design data found; using default values')
        else
            % Editfields
            app.ParamPnl_T_inj_EditField.Value	    = Struct.DesignReactiveTrTest.T_inj   ;
            app.ParamPnl_Q_EditField.Value     	    = Struct.DesignReactiveTrTest.Q       ;
            app.ParamPnl_rho_r_EditField.Value	    = Struct.DesignReactiveTrTest.rho_r   ;
            app.ParamPnl_cp_r_EditField.Value	    = Struct.DesignReactiveTrTest.cp_r    ;
            app.ParamPnl_M_rea_EditField.Value      = Struct.DesignReactiveTrTest.M_rea   ;
            app.ParamPnl_Arr_A_EditField.Value      = Struct.DesignReactiveTrTest.Arr_A   ;
            app.ParamPnl_Arr_Ea_EditField.Value     = Struct.DesignReactiveTrTest.Arr_Ea  ;
            
            % Channel data
            app.ParamPnlUITable.Data(1:nChan,5)		= Struct.DesignReactiveTrTest.Table_lambda_r'  	 	    ; 
            app.ParamPnlUITable.Data(1:nChan,6)		= Struct.DesignReactiveTrTest.Table_b'         	 	    ; 
            app.ParamPnlUITable.Data(1:nChan,8)		= Struct.DesignReactiveTrTest.Table_t_op'   	 	    ; 
            app.ParamPnlUITable.Data(1:nChan,10)	= Struct.DesignReactiveTrTest.Table_M_rea'     	 	    ; 
            app.ParamPnlUITable.Data(1:nChan,11)	= Struct.DesignReactiveTrTest.Table_Arr_A'     	 	    ; 
            app.ParamPnlUITable.Data(1:nChan,12)	= Struct.DesignReactiveTrTest.Table_Arr_Ea'     	    ;             

            % Plot options Editfields
            app.PlotOptions_t_min_EditField.Value	    = Struct.DesignReactiveTrTest.t_min    ;
            app.PlotOptions_t_max_EditField.Value	    = Struct.DesignReactiveTrTest.t_max    ;
            app.PlotOptions_c_min_EditField.Value	    = Struct.DesignReactiveTrTest.c_min    ;
            app.PlotOptions_c_max_EditField.Value	    = Struct.DesignReactiveTrTest.c_max    ;
            app.PlotOptions_T_min_EditField.Value	    = Struct.DesignReactiveTrTest.T_min    ;
            app.PlotOptions_T_max_EditField.Value	    = Struct.DesignReactiveTrTest.T_max    ;        
        end

        %------------------------------------------------------------ 
        % Update Parameter Table, Parameter Panel, x-axis of plot
        updateRTParamPnl(app);

        % Check for the uniqueness of the channel numbers
        ChnUniqueErr = ChnUniqueness(app,app.ParamTableDat);
        if (ChnUniqueErr == 1)
            app.ParamPnlUITable.Data = app.ParamTableDatOld;
            app.ParamPnlSumTDropDown.Value = app.ParamPnlSumTDropDownOld;
            app.ParamPnlxAxisDropDown.Value = app.ParamPnlxAxisDropDownOld;                
            return;
        end

        %Update Plot Options Table
        updatePlotOptTable(app)  
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;

            app.CommonVars_Hndl.T_res_EditField.Value    = app.ParamPnl_T_res_EditField.Value;
            app.CommonVars_Hndl.T_inj_EditField.Value    = app.ParamPnl_T_inj_EditField.Value;
            app.CommonVars_Hndl.cp_r_EditField.Value     = app.ParamPnl_cp_r_EditField.Value;
            app.CommonVars_Hndl.rho_r_EditField.Value    = app.ParamPnl_rho_r_EditField.Value;

            app.CommonVars_Hndl.Q_EditField.Value        = app.ParamPnl_Q_EditField.Value;
            app.CommonVars_Hndl.t_op_EditField.Value     = app.ParamPnl_t_op_EditField.Value;
            app.CommonVars_Hndl.M_con_EditField.Value    = app.ParamPnl_M_con_EditField.Value;
            app.CommonVars_Hndl.M_rea_EditField.Value    = app.ParamPnl_M_rea_EditField.Value;
            app.CommonVars_Hndl.Arr_A_EditField.Value    = app.ParamPnl_Arr_A_EditField.Value;
            app.CommonVars_Hndl.Arr_Ea_EditField.Value   = app.ParamPnl_Arr_Ea_EditField.Value;            
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;

            app.ParamPnl_T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
            app.ParamPnl_T_inj_EditField.Value      = app.CommonVars_Hndl.T_inj_EditField.Value;
            app.ParamPnl_cp_r_EditField.Value       = app.CommonVars_Hndl.cp_r_EditField.Value;
            app.ParamPnl_rho_r_EditField.Value      = app.CommonVars_Hndl.rho_r_EditField.Value;

            app.ParamPnl_Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;
            app.ParamPnl_t_op_EditField.Value       = app.CommonVars_Hndl.t_op_EditField.Value;
            app.ParamPnl_M_con_EditField.Value      = app.CommonVars_Hndl.M_con_EditField.Value;
            app.ParamPnl_M_rea_EditField.Value      = app.CommonVars_Hndl.M_rea_EditField.Value;
            app.ParamPnl_Arr_A_EditField.Value      = app.CommonVars_Hndl.Arr_A_EditField.Value;
            app.ParamPnl_Arr_Ea_EditField.Value     = app.CommonVars_Hndl.Arr_Ea_EditField.Value;               
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
            app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desTemperatureprofilesMenu
        function desTemperatureprofilesMenuSelected(app, event)
            app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;              
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
            app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;                                    
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
           app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;  
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
           app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Design Workflow -- Reactive Tracer';
            app.FileActionsMenu = uimenu(app.UIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.SavetracerplotdataMenu = uimenu(app.FileActionsMenu);
            app.SavetracerplotdataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetracerplotdataMenuSelected, true);
            app.SavetracerplotdataMenu.Text = 'Save tracer plot data';
            app.TaskControlMenu = uimenu(app.UIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofilesMenuSelected, true);
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.Enable = 'off';
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.UIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.ReaTracerLabel = uilabel(app.UIFigure);
            app.ReaTracerLabel.Text = 'Reactive Tracer Design';
            app.ParamPanel = uipanel(app.UIFigure);
            app.ParamPanel.Title = 'Parameters';
            app.ParamPnlNoChannelsLabel = uilabel(app.ParamPanel);
            app.ParamPnlNoChannelsLabel.Text = 'No. Channels';
            app.ParamPnlDrpxAxisLabel = uilabel(app.ParamPanel);
            app.ParamPnlDrpxAxisLabel.Text = 'x-Axis for Tracer Plot';
            app.ParamPnlxAxisDropDown = uidropdown(app.ParamPanel);
            app.ParamPnlxAxisDropDown.Items = {'Time', 'Distance'};
            app.ParamPnlxAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamPnlxAxisDropDownValueChanged, true);
            app.ParamPnlxAxisDropDown.Value = 'Time';
            app.AverageConcentrationsLabel = uilabel(app.ParamPanel);
            app.AverageConcentrationsLabel.Text = 'Average Concentrations';
            app.ParamPnlSumTDropDown = uidropdown(app.ParamPanel);
            app.ParamPnlSumTDropDown.Items = {'Yes', 'No'};
            app.ParamPnlSumTDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamPnlSumTDropDownValueChanged, true);
            app.ParamPnlSumTDropDown.Value = 'No';
            app.ParamPnlAddChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlAddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlAddChannelButtonPushed, true);
            app.ParamPnlAddChannelButton.Text = 'Add Channel';
            app.ParamPnlDeleteChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlDeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlDeleteChannelButtonPushed, true);
            app.ParamPnlDeleteChannelButton.Text = 'Delete Channel';
            app.ParamPnl_nChn_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_nChn_EditField.Limits = [1 10];
            app.ParamPnl_nChn_EditField.ValueDisplayFormat = '%.0f';
            app.ParamPnl_nChn_EditField.Editable = 'off';
            app.ParamPnl_nChn_EditField.Value = 1;
            app.ParamPnl_T_resLabel = uilabel(app.ParamPanel);
            app.ParamPnl_T_resLabel.Text = 'Res. Temp. (°C)';
            app.ParamPnl_T_res_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_res_EditField.Limits = [0 Inf];
            app.ParamPnl_T_res_EditField.ValueChangedFcn = createCallbackFcn(app, @ParamPnl_T_res_EditFieldValueChanged, true);
            app.ParamPnl_T_res_EditField.Value = 150;
            app.ParamPnl_T_inj_Label = uilabel(app.ParamPanel);
            app.ParamPnl_T_inj_Label.Text = 'Inj. Temp. (°C)';
            app.ParamPnl_T_inj_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_inj_EditField.Limits = [0 Inf];
            app.ParamPnl_T_inj_EditField.ValueChangedFcn = createCallbackFcn(app, @ParamPnl_T_inj_EditFieldValueChanged, true);
            app.ParamPnl_T_inj_EditField.Value = 100;
            app.ParamPnl_rho_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_rho_r_Label.Interpreter = 'html';
            app.ParamPnl_rho_r_Label.Text = 'Rock Dens. (kg/m<sup>3</sup>)';
            app.ParamPnl_rho_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_rho_r_EditField.Value = 2650;
            app.ParamPnl_cp_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_cp_r_Label.Text = 'cp, Rock (J/kg/°C)';
            app.ParamPnl_cp_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_cp_r_EditField.Value = 790;
            app.ParamPnl_t_op_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_t_op_EditField.Limits = [0 Inf];
            app.ParamPnl_t_op_EditField.Visible = 'off';
            app.ParamPnl_t_op_Label = uilabel(app.ParamPanel);
            app.ParamPnl_t_op_Label.Visible = 'off';
            app.ParamPnl_t_op_Label.Text = 'Op. Time (days)';
            app.ParamPnlUITable = uitable(app.ParamPanel);
            app.ParamPnlUITable.ColumnName = {'Channel'; 'v'; 'L'; 'alpha'; 'lambda-r'; 'b'; 'Q'; 't_op'; 'Mc'; 'Mr'; 'A'; 'Ea'; 'f'; 'Include'};
            app.ParamPnlUITable.RowName = {};
            app.ParamPnlUITable.ColumnEditable = [true true true true true true true true true false true true false true];
            app.ParamPnlUITable.CellEditCallback = createCallbackFcn(app, @ParamPnlUITableCellEdit, true);
            app.ParamPnlUITable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'alpha: calculated dispersivity (m)'; 'lambda: thermal conductivity (J/m/K)'; 'b: aperture'; 'Q: pumping rate (m^3/s)'; 't_op: operating time'; 'Mc: Mass conservative tracer (kg)'; 'Mr: Mass reactive tracer (kg)'; 'A: Arrhenius parameter (1/s)'; 'Ea: Arrhenius parameter (kJ/Mol)'};
            app.ParamPnl_Arr_A_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Arr_A_EditField.Visible = 'off';
            app.ParamPnl_Arr_A_EditField.Value = 2000000;
            app.ParamPnl_Arr_A_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Arr_A_Label.Visible = 'off';
            app.ParamPnl_Arr_A_Label.Text = 'A (1/s)';
            app.ParamPnl_Arr_Ea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Arr_Ea_EditField.Visible = 'off';
            app.ParamPnl_Arr_Ea_EditField.Value = 67600;
            app.ParamPnl_Arr_Ea_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Arr_Ea_Label.Visible = 'off';
            app.ParamPnl_Arr_Ea_Label.Text = 'Ea (kJ/Mol)';
            app.ParamPnl_Q_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Q_EditField.Visible = 'off';
            app.ParamPnl_Q_EditField.Value = 1;
            app.ParamPnl_Q_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Q_Label.Visible = 'off';
            app.ParamPnl_Q_Label.Interpreter = 'html';
            app.ParamPnl_Q_Label.Text = 'Pumping Rate (m<sup>3</sup>/s)';
            app.ParamPnl_M_con_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_M_con_EditField.Visible = 'off';
            app.ParamPnl_M_con_EditField.Value = 1;
            app.ParamPnl_M_con_Label = uilabel(app.ParamPanel);
            app.ParamPnl_M_con_Label.Visible = 'off';
            app.ParamPnl_M_con_Label.Text = 'Mass, con (kg)';
            app.ParamPnl_M_rea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_M_rea_EditField.Visible = 'off';
            app.ParamPnl_M_rea_EditField.Value = 1;
            app.ParamPnl_M_rea_Label = uilabel(app.ParamPanel);
            app.ParamPnl_M_rea_Label.Visible = 'off';
            app.ParamPnl_M_rea_Label.Text = 'Mass, rea (kg)';
            app.UpdatePlotButton_2 = uibutton(app.ParamPanel, 'push');
            app.UpdatePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton_2.Text = 'Calculate & Plot';
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plotting Options';
            app.PlotOptions_T_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_T_max_EditField.Limits = [0 Inf];
            app.PlotOptions_T_max_EditField.Value = Inf;
            app.PlotOptions_T_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_T_min_EditField.Limits = [0 Inf];
            app.PlotOptions_c_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_c_max_EditField.Limits = [0 Inf];
            app.PlotOptions_c_max_EditField.Value = Inf;
            app.PlotOptions_c_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_c_min_EditField.Limits = [0 Inf];
            app.PlotOptions_t_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_t_max_EditField.Limits = [0 Inf];
            app.PlotOptions_t_max_EditField.Value = 365;
            app.PlotOptions_t_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptions_t_min_EditField.Limits = [0 Inf];
            app.PlotOptions_T_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_T_Label.Text = 'Temp.';
            app.PlotOptions_c_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_c_Label.Text = 'Conc.';
            app.PlotOptions_t_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_t_Label.Text = 'Time';
            app.PlotOptions_Min_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_Min_Label.Text = 'Min';
            app.PlotOptions_Max_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_Max_Label.Text = 'Max';
            app.PlotOptPnlCurveChoiceRadioBtn = uibuttongroup(app.PlotOptionsPanel);
            app.PlotOptPnlCurveChoiceRadioBtn.SelectionChangedFcn = createCallbackFcn(app, @designPlotOptPnlCurveChoiceBtn, true);
            app.PlotOptPnlCurveChoiceRadioBtn.Title = 'Tracer Plots';
            app.PlotOptPnlCurveChoiceBtnRea = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnRea.Text = 'Reactive';
            app.PlotOptPnlCurveChoiceBtnRea.Value = true;
            app.PlotOptPnlCurveChoiceBtnReaAndCon = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnReaAndCon.Text = 'Reactive and Conservative';
            app.PlotOptPnlCurveChoiceBtnRatio = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnRatio.Text = 'Ratio (R/C)';
            app.UpdatePlotButton = uibutton(app.PlotOptionsPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plots with Current  Options';
            app.EqHelpButton = uibutton(app.UIFigure, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Equation Help';
            app.Eq = uiimage(app.UIFigure);
            app.Eq.ImageSource = fullfile(pathToMLAPP, 'appdata', 'ReactiveTracerEq.png');
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plots';
            app.UIaxis = uiaxes(app.PlotPanel);
            title(app.UIaxis, 'Reactive Tracer BTC')
            xlabel(app.UIaxis, 'Time')
            ylabel(app.UIaxis, 'Concentration')
            zlabel(app.UIaxis, 'Z')
            app.TempUIAxes = uiaxes(app.PlotPanel);
            title(app.TempUIAxes, 'Temperature Profile')
            xlabel(app.TempUIAxes, 'Relative Distance')
            ylabel(app.TempUIAxes, 'Temperature (oC)')
            zlabel(app.TempUIAxes, 'Z')
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.AverageRateCoefficientsLabel = uilabel(app.OutputPanel);
            app.AverageRateCoefficientsLabel.Text = 'Average Rate Coefficients';
            app.OutputPnlUITable = uitable(app.OutputPanel);
            app.OutputPnlUITable.ColumnName = {'Channel'; 'k_bar'};
            app.OutputPnlUITable.RowName = {};
            app.OutputPnlUITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08DesignReactiveTrTest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end